classdef medico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        RegistrardatosTab       matlab.ui.container.Tab
        EdadEditField           matlab.ui.control.NumericEditField
        EdadEditFieldLabel      matlab.ui.control.Label
        SexoDropDown            matlab.ui.control.DropDown
        SexoDropDownLabel       matlab.ui.control.Label
        ApellidoEditField       matlab.ui.control.EditField
        ApellidoEditFieldLabel  matlab.ui.control.Label
        NombreEditField         matlab.ui.control.EditField
        NombreEditFieldLabel    matlab.ui.control.Label
        GuardarButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        DiagnosticarTab         matlab.ui.container.Tab
        ImportFileLogButton     slrealtime.ui.control.ImportFileLogButton
        Image2                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
    archivoXML = 'pacientes.xml';

    % Verificar si ya existe el archivo XML
    if isfile(archivoXML)
        % Leer el archivo existente
        doc = xmlread(archivoXML);
        root = doc.getDocumentElement;
    else
        % Crear nuevo documento y nodo raíz <Pacientes>
        doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
        root = doc.getDocumentElement;
    end

    % Crear el nodo <Paciente>
    paciente = doc.createElement('Paciente');

    % Subnodos del paciente
    nombre = doc.createElement('Nombre');
    nombre.appendChild(doc.createTextNode(app.NombreEditField.Value));
    paciente.appendChild(nombre);

    apellido = doc.createElement('Apellido');
    apellido.appendChild(doc.createTextNode(app.ApellidoEditField.Value));
    paciente.appendChild(apellido);

    edad = doc.createElement('Edad');
    edad.appendChild(doc.createTextNode(num2str(app.EdadEditField.Value)));
    paciente.appendChild(edad);

    sexo = doc.createElement('Sexo');
    sexo.appendChild(doc.createTextNode(app.SexoDropDown.Value));  % O EditField si usas eso
    paciente.appendChild(sexo);

    % Añadir <Paciente> al nodo raíz <Pacientes>
    root.appendChild(paciente);

    % Guardar archivo actualizado
    xmlwrite(archivoXML, doc);

    % Confirmación
    uialert(app.UIFigure, 'Paciente guardado correctamente en pacientes.xml', 'Éxito');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrardatosTab = uitab(app.TabGroup);
            app.RegistrardatosTab.Title = 'Registrar datos';
            app.Image = uiimage(app.RegistrardatosTab);
            app.Image.ImageSource = 'logo.png';
            app.GuardarButton = uibutton(app.RegistrardatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NombreEditFieldLabel = uilabel(app.RegistrardatosTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.RegistrardatosTab, 'text');
            app.ApellidoEditFieldLabel = uilabel(app.RegistrardatosTab);
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.RegistrardatosTab, 'text');
            app.SexoDropDownLabel = uilabel(app.RegistrardatosTab);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.RegistrardatosTab);
            app.SexoDropDown.Items = {'Mujer', 'Hombre', 'Otro'};
            app.SexoDropDown.Value = 'Mujer';
            app.EdadEditFieldLabel = uilabel(app.RegistrardatosTab);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.RegistrardatosTab, 'numeric');
            app.DiagnosticarTab = uitab(app.TabGroup);
            app.DiagnosticarTab.Title = 'Diagnosticar';
            app.Image2 = uiimage(app.DiagnosticarTab);
            app.Image2.ImageSource = 'logo.png';
            app.ImportFileLogButton = slrealtime.ui.control.ImportFileLogButton(app.DiagnosticarTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end