classdef registrarse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Hyperlink                  matlab.ui.control.Hyperlink
        YatienecuentaLabel         matlab.ui.control.Label
        RegistrodeusuarioLabel     matlab.ui.control.Label
        EspecialidadDropDown       matlab.ui.control.DropDown
        EspecialidadDropDownLabel  matlab.ui.control.Label
        ContraseaEditField         matlab.ui.control.EditField
        ContraseaLabel             matlab.ui.control.Label
        UsuarioEditField           matlab.ui.control.EditField
        UsuarioLabel               matlab.ui.control.Label
        ApellidoEditField          matlab.ui.control.EditField
        ApellidoLabel              matlab.ui.control.Label
        NombreEditField            matlab.ui.control.EditField
        NombreLabel                matlab.ui.control.Label
        RegistrarseButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesion); 
           
        end

        % Button pushed function: RegistrarseButton
        function RegistrarseButtonPushed(app, event)
    archivo = 'usuarios.xlsx';

    % Crear la fila con el nuevo usuario
    nuevoUsuario = {
        app.NombreEditField.Value, ...
        app.ApellidoEditField.Value, ...
        app.UsuarioEditField.Value, ...
        app.ContraseaEditField.Value, ...
        app.RolDropDown.Value  % Nuevo campo
    };

    % Si el archivo ya existe, agregar al final
    if isfile(archivo)
        datos = readcell(archivo);
        datos(end+1, :) = nuevoUsuario;
    else
        % Crear archivo con encabezados
        datos = [{'Nombre', 'Apellido', 'Usuario', 'Contraseña', 'Especialidad'}; nuevoUsuario];
    end

    % Guardar en Excel
    writecell(datos, archivo);

       delete(app);
       nuevaApp = LoginApp(iniciosesion); 
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.RegistrarseButton = uibutton(app.UIFigure, 'push');
            app.RegistrarseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarseButtonPushed, true);
            app.RegistrarseButton.Text = 'Registrarse';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Nombre       ';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoLabel = uilabel(app.UIFigure);
            app.ApellidoLabel.Text = 'Apellido        ';
            app.ApellidoEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Usuario        ';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Contraseña  ';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.EspecialidadDropDownLabel = uilabel(app.UIFigure);
            app.EspecialidadDropDownLabel.Text = 'Especialidad';
            app.EspecialidadDropDown = uidropdown(app.UIFigure);
            app.EspecialidadDropDown.Items = {'Médico', 'Técnico de laboratorio'};
            app.EspecialidadDropDown.Value = 'Médico';
            app.RegistrodeusuarioLabel = uilabel(app.UIFigure);
            app.RegistrodeusuarioLabel.Text = 'Registro de usuario';
            app.YatienecuentaLabel = uilabel(app.UIFigure);
            app.YatienecuentaLabel.Text = '¿Ya tiene cuenta?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Iniciar sesión';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end